classdef Preprocessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OutputLabel2                  matlab.ui.control.Label
        InputLabel2                   matlab.ui.control.Label
        OutputLabel                   matlab.ui.control.Label
        InputLabel                    matlab.ui.control.Label
        ProcessButton                 matlab.ui.control.Button
        SelectoutputfolderButton      matlab.ui.control.Button
        SelectdataButton              matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        Dcm2niiTab                    matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        PhilipsprecisefloatCheckBox   matlab.ui.control.CheckBox
        SeriesinnameCheckBox          matlab.ui.control.CheckBox
        DescriptioninnameCheckBox     matlab.ui.control.CheckBox
        ADCTab                        matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        RegistertostructuralCheckBox  matlab.ui.control.CheckBox
        ResliceTab                    matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        MotionEddycorrectionCheckBox  matlab.ui.control.CheckBox
        DKIDTIfitCheckBox             matlab.ui.control.CheckBox
        DenoiseCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        filepath % location of input files
        outpath  % location of output files
        t1_path  % location of T1 to register to
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectdataButton
        function slctDataPressed(app, event)
            PrePrcIO.SelectData(app)
        end

        % Button pushed function: SelectoutputfolderButton
        function SlctOutPressed(app, event)
            PrePrcIO.SelectOutPath(app)
        end

        % Callback function: PhilipsprecisefloatCheckBox, ProcessButton
        function StartPrePrcPressed(app, event)
            
            switch app.TabGroup.SelectedTab.Title
                case 'Dcm2nii'
                    PrePrcDcm2nii.Start(app)
                case 'ADC'
                    PrePrcADC.Start(app)
                case 'Reslice'
                    PrePrcReslice.Start(app)
            end

            app.filepath = '';
            app.outpath  = '';
            app.ProcessButton.Enable = false;

        end

        % Value changed function: RegistertostructuralCheckBox
        function RegisterChanged(app, event)
            value = app.RegistertostructuralCheckBox.Value;
            if value
                PrePrcIO.SelectT1(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Dcm2niiTab = uitab(app.TabGroup);
            app.Dcm2niiTab.Title = 'Dcm2nii';
            app.GridLayout2 = uigridlayout(app.Dcm2niiTab);
            app.DescriptioninnameCheckBox = uicheckbox(app.GridLayout2);
            app.DescriptioninnameCheckBox.Text = 'Description in name';
            app.DescriptioninnameCheckBox.Value = true;
            app.SeriesinnameCheckBox = uicheckbox(app.GridLayout2);
            app.SeriesinnameCheckBox.Text = 'Series in name';
            app.SeriesinnameCheckBox.Value = true;
            app.PhilipsprecisefloatCheckBox = uicheckbox(app.GridLayout2);
            app.PhilipsprecisefloatCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartPrePrcPressed, true);
            app.PhilipsprecisefloatCheckBox.Text = 'Philips precise float';
            app.PhilipsprecisefloatCheckBox.Value = true;
            app.ADCTab = uitab(app.TabGroup);
            app.ADCTab.Title = 'ADC';
            app.GridLayout = uigridlayout(app.ADCTab);
            app.DenoiseCheckBox = uicheckbox(app.GridLayout);
            app.DenoiseCheckBox.Text = 'Denoise';
            app.DKIDTIfitCheckBox = uicheckbox(app.GridLayout);
            app.DKIDTIfitCheckBox.Text = 'DKI / DTI fit';
            app.MotionEddycorrectionCheckBox = uicheckbox(app.GridLayout);
            app.MotionEddycorrectionCheckBox.Text = 'Motion / Eddy correction';
            app.RegistertostructuralCheckBox = uicheckbox(app.GridLayout);
            app.RegistertostructuralCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegisterChanged, true);
            app.RegistertostructuralCheckBox.Text = 'Register to structural';
            app.ResliceTab = uitab(app.TabGroup);
            app.ResliceTab.Title = 'Reslice';
            app.GridLayout3 = uigridlayout(app.ResliceTab);
            app.SelectdataButton = uibutton(app.UIFigure, 'push');
            app.SelectdataButton.ButtonPushedFcn = createCallbackFcn(app, @slctDataPressed, true);
            app.SelectdataButton.Text = 'Select data';
            app.SelectoutputfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SlctOutPressed, true);
            app.SelectoutputfolderButton.Text = 'Select output folder';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StartPrePrcPressed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input: ';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.InputLabel2 = uilabel(app.UIFigure);
            app.InputLabel2.Text = '';
            app.OutputLabel2 = uilabel(app.UIFigure);
            app.OutputLabel2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end