classdef RMSStudio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        LoadLabelsMenu                matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        StudyMenu                     matlab.ui.container.Menu
        SwitchUserMenu                matlab.ui.container.Menu
        LoadDatabaseMenu              matlab.ui.container.Menu
        NextStudyMenu                 matlab.ui.container.Menu
        PreviousStudyMenu             matlab.ui.container.Menu
        ResetStudyMenu                matlab.ui.container.Menu
        LoadROIpointsMenu             matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        CoronalMenu                   matlab.ui.container.Menu
        SagittalMenu                  matlab.ui.container.Menu
        AxialMenu                     matlab.ui.container.Menu
        SwitchViewMenu                matlab.ui.container.Menu
        View1Menu                     matlab.ui.container.Menu
        View2Menu                     matlab.ui.container.Menu
        SwitchColoursMenu             matlab.ui.container.Menu
        AnalyseMenu                   matlab.ui.container.Menu
        Labels3DMenu                  matlab.ui.container.Menu
        LabelsStatsMenu               matlab.ui.container.Menu
        DrawMenu                      matlab.ui.container.Menu
        LabelsMenu                    matlab.ui.container.Menu
        DrawPolygonMenu               matlab.ui.container.Menu
        EditPolygonMenu               matlab.ui.container.Menu
        CircularROIMenu               matlab.ui.container.Menu
        MeasurementsMenu              matlab.ui.container.Menu
        LineMenu                      matlab.ui.container.Menu
        EllipseMenu                   matlab.ui.container.Menu
        ClearMenu                     matlab.ui.container.Menu
        UndoMenu                      matlab.ui.container.Menu
        RemoveSingleMenu              matlab.ui.container.Menu
        RemoveAllMenu                 matlab.ui.container.Menu
        SelectionSensitivityMenu      matlab.ui.container.Menu
        SegmentMenu                   matlab.ui.container.Menu
        MagicDrawMenu                 matlab.ui.container.Menu
        MagicDraw3DMenu               matlab.ui.container.Menu
        PointstoSegmentationMenu      matlab.ui.container.Menu
        Settings                      matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        UIAxes1                       matlab.ui.control.UIAxes
        CoronalButton                 matlab.ui.control.Button
        SagittalButton                matlab.ui.control.Button
        AxialButton                   matlab.ui.control.Button
        SliceSliderLabel              matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        MinvalSliderLabel             matlab.ui.control.Label
        MinvalSlider                  matlab.ui.control.Slider
        MaxvalSliderLabel             matlab.ui.control.Label
        MaxvalSlider                  matlab.ui.control.Slider
        ViewcontrolLabel              matlab.ui.control.Label
        BrightnessandslicemgmtLabel   matlab.ui.control.Label
        MagicdrawoptionsLabel         matlab.ui.control.Label
        SliceDecreaseButton           matlab.ui.control.Button
        SliceIncreaseButton           matlab.ui.control.Button
        Decrease4DButton              matlab.ui.control.Button
        Increase4DButton              matlab.ui.control.Button
        DSliderLabel                  matlab.ui.control.Label
        DSlider                       matlab.ui.control.Slider
        View1Button                   matlab.ui.control.Button
        View2Button                   matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes1Label                  matlab.ui.control.Label
        UIAxes2Label                  matlab.ui.control.Label
        AvailableStudiesListBoxLabel  matlab.ui.control.Label
        AvailableStudiesListBox       matlab.ui.control.ListBox
        DrawPolygonButton             matlab.ui.control.Button
        DrawLabel                     matlab.ui.control.Label
        EditPolygonButton             matlab.ui.control.Button
        AlignLRButton                 matlab.ui.control.Button
        AlignRLButton                 matlab.ui.control.Button
        AlignLabel                    matlab.ui.control.Label
        VisibleSlider                 matlab.ui.control.Switch
        VisibleLabel                  matlab.ui.control.Label
        SensitivitySliderLabel        matlab.ui.control.Label
        SensitivitySlider             matlab.ui.control.Slider
        AvailableimagesListBoxLabel   matlab.ui.control.Label
        AvailableimagesListBox        matlab.ui.control.ListBox
        AlgorithmDropDownLabel        matlab.ui.control.Label
        AlgorithmDropDown             matlab.ui.control.DropDown
        StatusLampLabel               matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        UserObjectsLabel              matlab.ui.control.Label
        UOBox                         matlab.ui.control.ListBox
        ContextMenu                   matlab.ui.container.ContextMenu
        DeleteMenu                    matlab.ui.container.Menu
        RenameMenu                    matlab.ui.container.Menu
        CopytoMenu                    matlab.ui.container.Menu
        TogglevisibilityMenu          matlab.ui.container.Menu
        ToggleinfoboxMenu             matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %GUI
        drawing,                    % Information for drawing
%         current_slice,              % Slice of the image (in focus) that is currently displayed 
        colors_list,                % List of colors used to differentiate all the user-defined objects
        current_folder,             % Path to study location on disk
        current_image,              % ???
        current_view,               % UIAxis which is currently in focus
%         current_4d_idx,             % ??
%         view_axis,                  % Axis of the current view       % Coronal = 1, Sagittal = 2, Axial = 3
        imIdx,                      % Index of the image in focus
        MinValue,                   % Minimum image value (everything <= MinValue -> 0)
        MaxValue,                   % Maximum image value (everything >= MaxValue -> 255)
        cScalePerImage              % Min and max display value per image
        imagePerAxis                %Stores which image is used per axis
        slicePerImage               %Stores which slice is used per image
        d4PerImage                  %Stores the 4d axis per image
        viewPerImage                %Stores which view (ax, cor, sag) is 
                                    %used per image
        selector_size               % Size (in %) of window when deleting objects
        mouse_pos                   % mouse position in events
        should_show_selection,      % ???
        selection_contour           % ???
        
        %Study
%         segmentation_list,          % Stores the segmentations 
%         seg_names_list,             % Stores the segmentation names 
%         roiPointList                % Stores the roiPoints
%         roiPointIndexList           % Stores the roiPointIndexes
%         measurement_list,           % Stores all the measurements 
%         measure_names_list,         % Stores all the measurement names 
%         measure_length_list,        % Stores all the measurement lengths
        backup_list                 % Stores a backup of all previous 
       
        %Items per view
        data,                       % The image of the view in focus
        userObjects,                % Stores all the user-drawn objects
        segmentation,               % Segmentations of the image in focus
        seg_names,                  % Names of all the segmentation objects
        measure_lines               % Stores all measurement objects
        measure_names,              % Names of all the measurement objects
        measure_length,             % Length of all the measurement objects
        roiPoints                   % All the points drawn in app.drawing.points
        roiPointIndex               %Stores the segmentation that all the 
                                    % ROI points belong to.
        points                      % Stores all current drawing points
        unsavedProgress             % Stores whether any unsaved progress exists
        
        %temporary items
        dragPoint                   %Stores the location from where to 
                                    % start dragging roi points.
        currentDragPoint            %Stores which point is currently being
                                    % dragged
        currentCircle               %Circular ROI which is currently being 
                                    % drawn
        tempDrawings                %Temporary items that get redrawn
        
        %Other
        
        user_profile                %Stores which user is currently working
        dataset                     %Stores the list of study directories.
        filepath                    %Stores the current directory
        zoomToggle                  %Stores zoomstatus
        Align                       %Stores align status (lr, rl)
        interpolateImages           = false %Boolean
        ctrl                        %Keeps track of the ctrl modifier
        lastEvents                  %Keeps track of when the last event was
        
        
    end
    
    methods (Access = public)
    
        % Just an wrapper for convenience
        function MouseClickedInImage(app,im,hit)
            Interaction.MouseClickedInImage(app, hit); 
        end
        
        function MouseDraggedInImage(app, im, hit)
            Interaction.MouseDraggedInImage(app, hit);
        end
        
        function MouseReleasedInImage(app, im, hit)
            Interaction.MouseReleasedInImage(app, hit)
        end
    
        % Update the Min-Max slider labels
        function UpdateMinMaxSlider(app)
            GUI.UpdateMinMaxSlider(app)
        end
    
        function h = SetupAxis(app, the_ax)
            h = GUI.SetupAxis(the_ax);
        end
            
        function ax = GetAxis(app, idx)
            %Handle for linking to the intended axis. Useful since the
            %axes can't be stored in a list it seems.
            
            if idx == 1
                ax = app.UIAxes1;
            elseif idx == 2
                ax = app.UIAxes2;
            else
                error("That figure doesn't exist")
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0, 'DefaultFigureRenderer', 'opengl');
%             rng(15061988);

            h1   = app.SetupAxis(app.UIAxes1);
            h2   = app.SetupAxis(app.UIAxes2);      
            
            set(h1,'ButtonDownFcn',@app.MouseClickedInImage);
%             set(h1,'WindowButtonMotionFcn',@app.MouseDraggedInImage);
%             set(h1,'WindowButtonUpFcn',@app.MouseReleasedInImage);
            set(h2,'ButtonDownFcn',@app.MouseClickedInImage);
%             set(h2,'WindowButtonMotionFcn',@app.MouseDraggedInImage);
%             set(h2,'WindowButtonUpFcn',@app.MouseReleasedInImage);
            
            app.current_view                = 1;
            app.selector_size               = 3;
            
            %drawing mode stores what mouse-interactions should do
            % 0 = nothing
            % 1 = ROI
            % 2 = edit ROI
            % 3 = line-measure
            % 4 = 'magic'
            % 5 = Circular ROI
            app.drawing.mode                = 0;
            app.tempDrawings                = [];
            
            %'magic' drawing = semi-automated selection
            app.drawing.magic_3d            = false;
            app.drawing.magic_sensitivity   = 2;
            app.drawing.magic_method        = 1;

            opengl('hardware');
            app.colors_list                 = zeros(1000,3);
            for ij=1:size(app.colors_list,1)
                app.colors_list(ij,:)       =...
                    [randi(100)/100 randi(100)/100 randi(100)/100];
                app.colors_list(ij,:)       =...
                    app.colors_list(ij,:)/norm(app.colors_list(ij,:));
            end
            app.StatusLampLabel.Text        = 'Idle';
            app.StatusLamp.Color            = 'g';
            app.imIdx           = 1;
            app.current_view                = 1;
            app.View1Button.BackgroundColor = [.96 .96 0];
            
            %init data storage
            app.imagePerAxis              = [1,1];
            app.slicePerImage             = [];
            [folder, ~, ~]  = fileparts(which('RMSStudio'));
            nii_path        = [folder '\nifti'];
            addpath(nii_path);
            
            app.unsavedProgress           = false;
            
        end

        % Menu selected function: LoadMenu
        function LoadButtonPushed(app, event)
            Interaction.LoadStudy(app)
        end

        % Callback function: AxialButton, AxialMenu
        function AxialButtonPushed(app, event)
            Interaction.ChangeViewAxis(app, 3)
        end

        % Callback function: SagittalButton, SagittalMenu
        function SagittalButtonPushed(app, event)
            Interaction.ChangeViewAxis(app, 2)
        end

        % Callback function: CoronalButton, CoronalMenu
        function CoronalButtonPushed(app, event)
            Interaction.ChangeViewAxis(app, 1)
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            Interaction.UpdateSlice(app, changingValue, app.current_view)
        end

        % Menu selected function: RemoveSingleMenu
        function SelectDeleteButtonPushed(app, event)
            Interaction.RemoveSingle(app)
        end

        % Menu selected function: SaveMenu
        function SaveeditedsegmButtonPushed(app, event)
            Interaction.Save(app);
        end

        % Value changing function: MaxvalSlider
        function MaxvalSliderValueChanging(app, event)
         
            changingValue = event.Value;
            next_maxvalue = changingValue/100*app.MaxValue;
            cVals           = app.cScalePerImage{app.imIdx};
            cMinVal         = cVals(1);
            if cMinVal >= next_maxvalue
                next_maxvalue = cMinVal+0.001;
            end
            app.cScalePerImage{app.imIdx} = [cMinVal, next_maxvalue];
            Graphics.UpdateImage(app);
            
        end

        % Value changing function: MinvalSlider
        function MinvalSliderValueChanging(app, event)
            changingValue = event.Value;
            next_minvalue = changingValue/100*app.MaxValue;
            cVals           = app.cScalePerImage{app.imIdx};
            cMaxVal         = cVals(2);
            if cMaxVal <= next_minvalue
                next_minvalue = cMaxVal - 0.001;
            end
            app.cScalePerImage{app.imIdx} = [next_minvalue, cMaxVal];
            Graphics.UpdateImage(app);
        end

        % Menu selected function: UndoMenu
        function UndoButtonPushed(app, event)
            Interaction.Undo(app)
        end

        % Menu selected function: SelectionSensitivityMenu
        function SelectionsensitivityButtonPushed(app, event)
            answer = inputdlg(['Please input the new sensitivity in pixel, (current ' num2str(app.selector_size) ')']);
            if(isempty(answer))
                return
            end
            answer = answer{1};
            if(ischar(answer))
                answer = str2double(answer);
            end
            if(answer > 0 && answer <= 10)
                app.selector_size = answer;
            else
                uialert(uifigure,'Invalid values! 0 < x <= 10 pixels','');
            end
        end

        % Callback function: DrawPolygonButton, DrawPolygonMenu
        function DrawpolygonButtonPushed(app, event)
            Interaction.DrawPolygon(app)
        end

        % Menu selected function: RemoveAllMenu
        function DeleteROIsALLButtonPushed(app, event)
            Interaction.DeleteROIsAndMeasurements(app)
        end

        % Menu selected function: MagicDrawMenu
        function MagicdrawButtonPushed(app, event)
            Interaction.MagicDraw(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            Interaction.UIKeyPress(app, event)
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            GUI.Scroll(app, event)
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            GUI.SensitivitySlider(app)
        end

        % Callback function
        function DCheckBoxValueChanged(app, event)
            value = app.DCheckBox.Value;
            app.drawing.magic_3d = value;
        end

        % Value changed function: AvailableimagesListBox
        function AvailableimagesListBoxValueChanged(app, event)
            Interaction.ListBoxChanged(app)
        end

        % Menu selected function: ResetStudyMenu
        function ResetstudyButtonPushed(app, event)
            Interaction.ResetStudy(app)
        end

        % Callback function
        function AlignlabelsButtonPushed(app, event)
            Interaction.RegisterSelectedImageToDialog(app);
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            Interaction.AlgorithmChanged(app)
        end

        % Menu selected function: LineMenu
        function MeasureLineButtonPushed(app, event)
            Interaction.MeasureLine(app)
        end

        % Button pushed function: SliceDecreaseButton
        function SliceDecreaseButtonPushed(app, event)
            Interaction.UpdateSlice(app, ...
                app.slicePerImage(app.imIdx) - 1, app.current_view);
        end

        % Button pushed function: SliceIncreaseButton
        function SliceIncreaseButtonPushed(app, event)
            Interaction.UpdateSlice(app, ...
                app.slicePerImage(app.imIdx) + 1, app.current_view);    
        end

        % Menu selected function: SwitchColoursMenu
        function ShufflecolorsButtonPushed(app, event)
            Interaction.ShuffleColors(app)
        end

        % Menu selected function: EllipseMenu
        function MeasureAutoButtonPushed(app, event)
           Interaction.PerformAutomaticEllipseMeasurement(app);
        end

        % Button pushed function: Decrease4DButton
        function Decrease4DButtonPushed(app, event)
            Interaction.Update4D(app, ...
                app.d4PerImage(app.imIdx) - 1)
        end

        % Button pushed function: Increase4DButton
        function Increase4DButtonPushed(app, event)
            Interaction.Update4D(app, ...
                app.d4PerImage(app.imIdx) + 1)       
        end

        % Value changing function: DSlider
        function DSliderValueChanging(app, event)
            changingValue = event.Value;
            Interaction.Update4D(app, round(changingValue))                      
        end

        % Callback function: View1Button, View1Menu
        function View1ButtonPushed(app, event)
            Backups.CreateBackup(app)
            Interaction.SwitchViewAndFocus(app,1,'View1Button');
        end

        % Callback function: View2Button, View2Menu
        function View2ButtonPushed(app, event)
            Backups.CreateBackup(app)
            Interaction.SwitchViewAndFocus(app,2,'View2Button');
        end

        % Callback function
        function Button_7Pushed(app, event)
            
        end

        % Menu selected function: Labels3DMenu
        function Labels3DButtonPushed(app, event)
            Plotting.Labels3DView(app);
        end

        % Menu selected function: LabelsStatsMenu
        function LabelsstatsButtonPushed(app, event)
            Plotting.LabelsStatsPlot(app);
        end

        % Callback function
        function debugButtonPushed(app, event)
            Interaction.Debug(app)
        end

        % Callback function
        function debugButton2Pushed(app, event)
            Interaction.Debug2(app)
        end

        % Callback function
        function CreateBackupButtonPushed(app, event)
            Interaction.MakeBackup(app)
        end

        % Menu selected function: SwitchUserMenu
        function SwitchUserButtonPressed(app, event)
            Interaction.Save(app);       
            Interaction.PromptProfile(app)     
            %TODO load new uobjs
%             IOUtils.PrepareStudy(app, app.filepath)
        end

        % Menu selected function: LoadDatabaseMenu
        function LoadSetButtonPushed(app, event)
            Database.PrepareDatabase(app)
        end

        % Menu selected function: PreviousStudyMenu
        function PrevStudyButtonPushed(app, event)
            Database.PrevStudy(app)
        end

        % Menu selected function: NextStudyMenu
        function NextStudyButtonPushed(app, event)
            Database.NextStudy(app)
        end

        % Value changed function: UOBox
        function UOBoxValueChanged(app, event)
            Interaction.UOBoxChanged(app)
        end

        % Value changed function: AvailableStudiesListBox
        function AvailableStudiesListBoxValueChanged(app, event)
            Database.SwitchToStudy(app)
        end

        % Menu selected function: LoadLabelsMenu
        function LoadLabelsButtonPushed(app, event)
            %Loads a new segmentation for the current image.
            Interaction.LoadNewLabels(app)
        end

        % Callback function: EditPolygonButton, EditPolygonMenu
        function EditPolygon(app, event)
            %Toggles a mode where clicking / draggin near a polygon point 
            % enables the user to move that point.
            Interaction.EditPolygon(app)
        end

        % Menu selected function: MagicDraw3DMenu
        function MagicDraw3DToggle(app, event)
            value = app.MagicDraw3DMenu.Checked;
            app.drawing.magic_3d = value;
        end

        % Menu selected function: LoadROIpointsMenu
        function loadROIPointsButtonPushed(app, event)
          Interaction.LoadROIPoints(app)  
        end

        % Menu selected function: PointstoSegmentationMenu
        function PointsToSegmentationPressed(app, event)
            Interaction.PointsToSegmentation(app)
        end

        % Button pushed function: AlignLRButton
        function AlignLeftToRightAlignLRButton(app, event)
            Interaction.AlignLeftRight(app,'LR')
        end

        % Button pushed function: AlignRLButton
        function AlignRightToLeftButton(app, event)
            Interaction.AlignLeftRight(app,'RL')
        end

        % Menu selected function: CircularROIMenu
        function CircularROI(app, event)
            Interaction.CircularROI(app)
        end

        % Value changed function: VisibleSlider
        function VisibleSliderChanged(app, event)
            value = app.VisibleSlider.Value;
            GUI.UpdateUOVisibility(app, value);
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            Interaction.UIKeyRelease(app, event)
        end

        % Menu selected function: DeleteMenu
        function DeleteUO(app, event)
            Objects.DeleteUO(app);
        end

        % Menu selected function: CopytoMenu
        function CopyUO(app, event)
            Objects.CopyUOTo(app)
        end

        % Menu selected function: RenameMenu
        function RenameUO(app, event)
            Objects.RenameUO(app)
        end

        % Menu selected function: TogglevisibilityMenu
        function ToggleUO(app, event)
            Objects.ToggleVisibleUO(app)
        end

        % Menu selected function: ToggleinfoboxMenu
        function ToggleUOBox(app, event)
            Objects.ToggleVisibleUOInfoBox(app)
        end

        % Menu selected function: ExitMenu
        function ExitProgram(app, event)
            Interaction.Exit(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'RMSStudio';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu.Text = 'Load';
            app.LoadLabelsMenu = uimenu(app.FileMenu);
            app.LoadLabelsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadLabelsButtonPushed, true);
            app.LoadLabelsMenu.Text = 'Load Labels';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveeditedsegmButtonPushed, true);
            app.SaveMenu.Text = 'Save';
            app.StudyMenu = uimenu(app.FileMenu);
            app.StudyMenu.Text = 'Study';
            app.SwitchUserMenu = uimenu(app.StudyMenu);
            app.SwitchUserMenu.MenuSelectedFcn = createCallbackFcn(app, @SwitchUserButtonPressed, true);
            app.SwitchUserMenu.Text = 'Switch User';
            app.LoadDatabaseMenu = uimenu(app.StudyMenu);
            app.LoadDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSetButtonPushed, true);
            app.LoadDatabaseMenu.Text = 'Load Database';
            app.NextStudyMenu = uimenu(app.StudyMenu);
            app.NextStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @NextStudyButtonPushed, true);
            app.NextStudyMenu.Text = 'Next Study';
            app.PreviousStudyMenu = uimenu(app.StudyMenu);
            app.PreviousStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @PrevStudyButtonPushed, true);
            app.PreviousStudyMenu.Text = 'Previous Study';
            app.ResetStudyMenu = uimenu(app.StudyMenu);
            app.ResetStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetstudyButtonPushed, true);
            app.ResetStudyMenu.Text = 'Reset Study';
            app.LoadROIpointsMenu = uimenu(app.FileMenu);
            app.LoadROIpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @loadROIPointsButtonPushed, true);
            app.LoadROIpointsMenu.Text = 'Load ROI points';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitProgram, true);
            app.ExitMenu.Text = 'Exit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Enable = 'off';
            app.ViewMenu.Text = 'View';
            app.CoronalMenu = uimenu(app.ViewMenu);
            app.CoronalMenu.MenuSelectedFcn = createCallbackFcn(app, @CoronalButtonPushed, true);
            app.CoronalMenu.Text = 'Coronal';
            app.SagittalMenu = uimenu(app.ViewMenu);
            app.SagittalMenu.MenuSelectedFcn = createCallbackFcn(app, @SagittalButtonPushed, true);
            app.SagittalMenu.Text = 'Sagittal';
            app.AxialMenu = uimenu(app.ViewMenu);
            app.AxialMenu.MenuSelectedFcn = createCallbackFcn(app, @AxialButtonPushed, true);
            app.AxialMenu.Text = 'Axial';
            app.SwitchViewMenu = uimenu(app.ViewMenu);
            app.SwitchViewMenu.Text = 'Switch View';
            app.View1Menu = uimenu(app.SwitchViewMenu);
            app.View1Menu.MenuSelectedFcn = createCallbackFcn(app, @View1ButtonPushed, true);
            app.View1Menu.Text = 'View 1';
            app.View2Menu = uimenu(app.SwitchViewMenu);
            app.View2Menu.MenuSelectedFcn = createCallbackFcn(app, @View2ButtonPushed, true);
            app.View2Menu.Text = 'View 2';
            app.SwitchColoursMenu = uimenu(app.ViewMenu);
            app.SwitchColoursMenu.MenuSelectedFcn = createCallbackFcn(app, @ShufflecolorsButtonPushed, true);
            app.SwitchColoursMenu.Text = 'Switch Colours';
            app.AnalyseMenu = uimenu(app.UIFigure);
            app.AnalyseMenu.Enable = 'off';
            app.AnalyseMenu.Text = 'Analyse';
            app.Labels3DMenu = uimenu(app.AnalyseMenu);
            app.Labels3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Labels3DButtonPushed, true);
            app.Labels3DMenu.Text = 'Labels 3D';
            app.LabelsStatsMenu = uimenu(app.AnalyseMenu);
            app.LabelsStatsMenu.MenuSelectedFcn = createCallbackFcn(app, @LabelsstatsButtonPushed, true);
            app.LabelsStatsMenu.Text = 'Labels Stats';
            app.DrawMenu = uimenu(app.UIFigure);
            app.DrawMenu.Enable = 'off';
            app.DrawMenu.Text = 'Draw';
            app.LabelsMenu = uimenu(app.DrawMenu);
            app.LabelsMenu.Text = 'Labels';
            app.DrawPolygonMenu = uimenu(app.LabelsMenu);
            app.DrawPolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawpolygonButtonPushed, true);
            app.DrawPolygonMenu.Text = 'Draw Polygon';
            app.EditPolygonMenu = uimenu(app.LabelsMenu);
            app.EditPolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @EditPolygon, true);
            app.EditPolygonMenu.Text = 'Edit Polygon';
            app.CircularROIMenu = uimenu(app.LabelsMenu);
            app.CircularROIMenu.MenuSelectedFcn = createCallbackFcn(app, @CircularROI, true);
            app.CircularROIMenu.Text = 'Circular ROI';
            app.MeasurementsMenu = uimenu(app.DrawMenu);
            app.MeasurementsMenu.Text = 'Measurements';
            app.LineMenu = uimenu(app.MeasurementsMenu);
            app.LineMenu.MenuSelectedFcn = createCallbackFcn(app, @MeasureLineButtonPushed, true);
            app.LineMenu.Text = 'Line';
            app.EllipseMenu = uimenu(app.MeasurementsMenu);
            app.EllipseMenu.MenuSelectedFcn = createCallbackFcn(app, @MeasureAutoButtonPushed, true);
            app.EllipseMenu.Text = 'Ellipse';
            app.ClearMenu = uimenu(app.DrawMenu);
            app.ClearMenu.Text = 'Clear';
            app.UndoMenu = uimenu(app.ClearMenu);
            app.UndoMenu.MenuSelectedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoMenu.Text = 'Undo';
            app.RemoveSingleMenu = uimenu(app.ClearMenu);
            app.RemoveSingleMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectDeleteButtonPushed, true);
            app.RemoveSingleMenu.Text = 'Remove Single';
            app.RemoveAllMenu = uimenu(app.ClearMenu);
            app.RemoveAllMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteROIsALLButtonPushed, true);
            app.RemoveAllMenu.Text = 'Remove All';
            app.SelectionSensitivityMenu = uimenu(app.ClearMenu);
            app.SelectionSensitivityMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectionsensitivityButtonPushed, true);
            app.SelectionSensitivityMenu.Text = 'Selection Sensitivity';
            app.SegmentMenu = uimenu(app.UIFigure);
            app.SegmentMenu.Enable = 'off';
            app.SegmentMenu.Text = 'Segment';
            app.MagicDrawMenu = uimenu(app.SegmentMenu);
            app.MagicDrawMenu.MenuSelectedFcn = createCallbackFcn(app, @MagicdrawButtonPushed, true);
            app.MagicDrawMenu.Text = 'Magic Draw';
            app.MagicDraw3DMenu = uimenu(app.SegmentMenu);
            app.MagicDraw3DMenu.MenuSelectedFcn = createCallbackFcn(app, @MagicDraw3DToggle, true);
            app.MagicDraw3DMenu.Checked = 'on';
            app.MagicDraw3DMenu.Text = 'Magic Draw 3D';
            app.PointstoSegmentationMenu = uimenu(app.SegmentMenu);
            app.PointstoSegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @PointsToSegmentationPressed, true);
            app.PointstoSegmentationMenu.Text = 'Points to Segmentation';
            app.Settings = uimenu(app.UIFigure);
            app.Settings.Text = 'Settings';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes1 = uiaxes(app.GridLayout);
            app.CoronalButton = uibutton(app.GridLayout, 'push');
            app.CoronalButton.ButtonPushedFcn = createCallbackFcn(app, @CoronalButtonPushed, true);
            app.CoronalButton.Enable = 'off';
            app.CoronalButton.Text = 'Coronal';
            app.SagittalButton = uibutton(app.GridLayout, 'push');
            app.SagittalButton.ButtonPushedFcn = createCallbackFcn(app, @SagittalButtonPushed, true);
            app.SagittalButton.Enable = 'off';
            app.SagittalButton.Text = 'Sagittal';
            app.AxialButton = uibutton(app.GridLayout, 'push');
            app.AxialButton.ButtonPushedFcn = createCallbackFcn(app, @AxialButtonPushed, true);
            app.AxialButton.Enable = 'off';
            app.AxialButton.Text = 'Axial';
            app.SliceSliderLabel = uilabel(app.GridLayout);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.GridLayout);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.Enable = 'off';
            app.MinvalSliderLabel = uilabel(app.GridLayout);
            app.MinvalSliderLabel.Text = 'Min val';
            app.MinvalSlider = uislider(app.GridLayout);
            app.MinvalSlider.ValueChangingFcn = createCallbackFcn(app, @MinvalSliderValueChanging, true);
            app.MinvalSlider.Enable = 'off';
            app.MaxvalSliderLabel = uilabel(app.GridLayout);
            app.MaxvalSliderLabel.Text = 'Max val';
            app.MaxvalSlider = uislider(app.GridLayout);
            app.MaxvalSlider.ValueChangingFcn = createCallbackFcn(app, @MaxvalSliderValueChanging, true);
            app.MaxvalSlider.Enable = 'off';
            app.ViewcontrolLabel = uilabel(app.GridLayout);
            app.ViewcontrolLabel.Text = 'View control';
            app.BrightnessandslicemgmtLabel = uilabel(app.GridLayout);
            app.BrightnessandslicemgmtLabel.Text = 'Brightness and slice mgmt.';
            app.MagicdrawoptionsLabel = uilabel(app.GridLayout);
            app.MagicdrawoptionsLabel.Text = 'Magic draw options';
            app.SliceDecreaseButton = uibutton(app.GridLayout, 'push');
            app.SliceDecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @SliceDecreaseButtonPushed, true);
            app.SliceDecreaseButton.Enable = 'off';
            app.SliceDecreaseButton.Text = '-';
            app.SliceIncreaseButton = uibutton(app.GridLayout, 'push');
            app.SliceIncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @SliceIncreaseButtonPushed, true);
            app.SliceIncreaseButton.Enable = 'off';
            app.SliceIncreaseButton.Text = '+';
            app.Decrease4DButton = uibutton(app.GridLayout, 'push');
            app.Decrease4DButton.ButtonPushedFcn = createCallbackFcn(app, @Decrease4DButtonPushed, true);
            app.Decrease4DButton.Enable = 'off';
            app.Decrease4DButton.Text = '-';
            app.Increase4DButton = uibutton(app.GridLayout, 'push');
            app.Increase4DButton.ButtonPushedFcn = createCallbackFcn(app, @Increase4DButtonPushed, true);
            app.Increase4DButton.Enable = 'off';
            app.Increase4DButton.Text = '+';
            app.DSliderLabel = uilabel(app.GridLayout);
            app.DSliderLabel.Text = '4D';
            app.DSlider = uislider(app.GridLayout);
            app.DSlider.ValueChangingFcn = createCallbackFcn(app, @DSliderValueChanging, true);
            app.DSlider.Enable = 'off';
            app.View1Button = uibutton(app.GridLayout, 'push');
            app.View1Button.ButtonPushedFcn = createCallbackFcn(app, @View1ButtonPushed, true);
            app.View1Button.Enable = 'off';
            app.View1Button.Text = '1';
            app.View2Button = uibutton(app.GridLayout, 'push');
            app.View2Button.ButtonPushedFcn = createCallbackFcn(app, @View2ButtonPushed, true);
            app.View2Button.Enable = 'off';
            app.View2Button.Text = '2';
            app.UIAxes2 = uiaxes(app.GridLayout);
            app.UIAxes1Label = uilabel(app.GridLayout);
            app.UIAxes1Label.Text = {'View 1'; ''};
            app.UIAxes2Label = uilabel(app.GridLayout);
            app.UIAxes2Label.Text = {'View 2'; ''};
            app.AvailableStudiesListBoxLabel = uilabel(app.GridLayout);
            app.AvailableStudiesListBoxLabel.Text = 'Available Studies';
            app.AvailableStudiesListBox = uilistbox(app.GridLayout);
            app.AvailableStudiesListBox.Items = {};
            app.AvailableStudiesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableStudiesListBoxValueChanged, true);
            app.AvailableStudiesListBox.Value = {};
            app.DrawPolygonButton = uibutton(app.GridLayout, 'push');
            app.DrawPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @DrawpolygonButtonPushed, true);
            app.DrawPolygonButton.Enable = 'off';
            app.DrawPolygonButton.Text = 'Draw Polygon';
            app.DrawLabel = uilabel(app.GridLayout);
            app.DrawLabel.Text = 'Draw';
            app.EditPolygonButton = uibutton(app.GridLayout, 'push');
            app.EditPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @EditPolygon, true);
            app.EditPolygonButton.Enable = 'off';
            app.EditPolygonButton.Text = 'Edit Polygon';
            app.AlignLRButton = uibutton(app.GridLayout, 'push');
            app.AlignLRButton.ButtonPushedFcn = createCallbackFcn(app, @AlignLeftToRightAlignLRButton, true);
            app.AlignLRButton.Enable = 'off';
            app.AlignLRButton.Text = '←';
            app.AlignRLButton = uibutton(app.GridLayout, 'push');
            app.AlignRLButton.ButtonPushedFcn = createCallbackFcn(app, @AlignRightToLeftButton, true);
            app.AlignRLButton.Enable = 'off';
            app.AlignRLButton.Text = '→';
            app.AlignLabel = uilabel(app.GridLayout);
            app.AlignLabel.Text = 'Align';
            app.VisibleSlider = uiswitch(app.GridLayout, 'slider');
            app.VisibleSlider.ValueChangedFcn = createCallbackFcn(app, @VisibleSliderChanged, true);
            app.VisibleSlider.Enable = 'off';
            app.VisibleSlider.Value = 'On';
            app.VisibleLabel = uilabel(app.GridLayout);
            app.VisibleLabel.Text = 'Visible';
            app.SensitivitySliderLabel = uilabel(app.GridLayout);
            app.SensitivitySliderLabel.Text = 'Sensitivity';
            app.SensitivitySlider = uislider(app.GridLayout);
            app.SensitivitySlider.Limits = [0 10];
            app.SensitivitySlider.MajorTicks = [0 5 10];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SensitivitySlider.Enable = 'off';
            app.SensitivitySlider.Value = 2;
            app.AvailableimagesListBoxLabel = uilabel(app.GridLayout);
            app.AvailableimagesListBoxLabel.Text = 'Available images';
            app.AvailableimagesListBox = uilistbox(app.GridLayout);
            app.AvailableimagesListBox.Items = {};
            app.AvailableimagesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableimagesListBoxValueChanged, true);
            app.AvailableimagesListBox.Value = {};
            app.AlgorithmDropDownLabel = uilabel(app.GridLayout);
            app.AlgorithmDropDownLabel.Enable = 'off';
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.GridLayout);
            app.AlgorithmDropDown.Items = {'Upper thresh', 'Lower thresh', 'Active contours', 'Active contours (negative)'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Enable = 'off';
            app.AlgorithmDropDown.Value = 'Upper thresh';
            app.StatusLampLabel = uilabel(app.GridLayout);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.GridLayout);
            app.UserObjectsLabel = uilabel(app.GridLayout);
            app.UserObjectsLabel.Text = 'User Objects';
            app.UOBox = uilistbox(app.GridLayout);
            app.UOBox.Items = {};
            app.UOBox.ValueChangedFcn = createCallbackFcn(app, @UOBoxValueChanged, true);
            app.UOBox.Value = {};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.UOBox.ContextMenu = app.ContextMenu;
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteUO, true);
            app.DeleteMenu.Text = 'Delete';
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameUO, true);
            app.RenameMenu.Text = 'Rename';
            app.CopytoMenu = uimenu(app.ContextMenu);
            app.CopytoMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyUO, true);
            app.CopytoMenu.Text = 'Copy to';
            app.TogglevisibilityMenu = uimenu(app.ContextMenu);
            app.TogglevisibilityMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleUO, true);
            app.TogglevisibilityMenu.Text = 'Toggle visibility';
            app.ToggleinfoboxMenu = uimenu(app.ContextMenu);
            app.ToggleinfoboxMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleUOBox, true);
            app.ToggleinfoboxMenu.Text = 'Toggle infobox';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RMSStudio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end