classdef SegManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        SegTable      matlab.ui.control.Table
        PatientTable  matlab.ui.control.Table
    end

    
    properties (Access = public)
        dbPath % Path to the database.json file that contains the info for 
                %the current dataset
        mainApp %application which launches the SegManager

        pTable  %Table with patient info
        sTable  %Table with segmentation info
        dlg     %progressdialogue
        sessionLst  % Keeps track of the session for each segmentation in STable
    end

    methods (Access = private)
        
        function GenerateDatabase(app)
            %if no database.json file exists, create one
            %Go over all folders

            app.dlg = uiprogressdlg(app.UIFigure, 'Title',...
                'Generating database');

            app.ResetDatabase();

            %Find all rmsstudio folders
            sessions = dir(fullfile(app.mainApp.datasetPath, ...
                '**/rmsstudio'));
            sessions = unique({sessions.folder}); %weird step...

            %Go over each scan session, add to pTable
            for i = 1:length(sessions)
                
                session         = sessions{i};
                [base, ~, ~]    = fileparts(session);
                [base, sessionName, ~] = fileparts(base);
                [~, pid, ~]            = fileparts(base);
                nSegs           = app.FindSegmentations(session);
                %also adds to sTable

                app.pTable = [app.pTable; ...
                {pid, sessionName, nSegs}];

                app.dlg.Value = i/length(sessions);
            end

            app.SegTable.Data = app.sTable;
            app.PatientTable.Data = app.pTable;    

            close(app.dlg)
            app.ResizeTables()

        end

        function nSegs = FindSegmentations(app, path)
            %Find the paths to all the segmentation .json files located in 
            %subfolders of the current path. 
            %Adds profile, scan, segName, slices & comment to sTable
            %returns: amount of segmentations in path
            
            %find all *segmentation.json files

            segFiles = dir(fullfile(path, '**/*-segmentation.json'));
            for i = 1:length(segFiles)
                segfile = segFiles(i);

                [base, subf1, ~]    = fileparts(segfile.folder);
                [~, subf2, ~]       = fileparts(base);

                if strcmp(subf2, 'rmsstudio')     %no profile-subfolder
                    profile = '-';
                    scan    = subf1;
                else    %with profile subfolder
                    profile = subf1;
                    scan    = subf2;
                end

                name                = strrep(segfile.name, ...
                    '-segmentation.json', '');

                %load json
                fid  = fopen(fullfile(segfile.folder, segfile.name), 'r');
                txt  = fread(fid,inf);
                txt  = char(txt');
                fclose(fid);
                data = jsondecode(txt);
    
                if isfield(data, 'viewDim')
                    viewDim = data.viewDim;
                else
                    viewDim = 3;
                end

                if isfield(data, 'comment')
                    comment = data.comment;
                else
                    comment = '';
                end

                %find slices 
                points = data.points;
                slices = unique(points(:, viewDim));

                app.sTable = [app.sTable; ...
                    {profile, scan, name, slices, comment}];

                app.sessionLst = [app.sessionLst; path];
                
            end

            nSegs = length(segFiles);
        end

        function ResetDatabase(app)

            %Init tables
            app.pTable = table();
            app.pTable.Patient_ID = {};
            app.pTable.Session = {};
            app.pTable.Segmentations = {};

            app.sTable = table();
            app.sTable.Profile = {};
            app.sTable.Scan = {};
            app.sTable.Name = {};
            app.sTable.Slice = {};
            app.sTable.Comment = {};

            %init sessionList
            app.sessionLst = {};
        end

        function HighlightRows(app, idx)
            app.ResetHighlights()
            s = uistyle("BackgroundColor", [0.25, 0.25, 0.35]);
            addStyle(app.SegTable, s, "row", idx);
            scroll(app.SegTable, 'row', min(1, min(idx)))
        end

        function ResetHighlights(app)
            s = uistyle("BackgroundColor", [0.15, 0.15, 0.15]);
            addStyle(app.SegTable, s);
        end

        function ResizeTables(app)
            %Resizes the table to get rid of the ugly white background.
            %Very empirical size rule: 
            %With font size 1/8th of an inch,
            %height of table = (numRows + 1) * rowHeight * 1.85 + 5
            %The +5 might need to change with different screen resolutions

            ppi         = get(0,'ScreenPixelsPerInch');
            rowHeight   = ppi/8;

            yTop        = app.PatientTable.Position(2) + ...
                app.PatientTable.Position(4);

            %PatientTable
            numRows     = size(app.pTable,1);
            deltaY      = (numRows + 1) * rowHeight * 1.85 + 5;
            if deltaY < app.UIFigure.Position(4) - 100
                newBottom   = yTop - deltaY;
                app.PatientTable.Position(2) = newBottom;
                app.PatientTable.Position(4) = deltaY;
            end

            %SegTable
            numRows     = size(app.sTable,1);
            deltaY      = (numRows + 1) * rowHeight * 1.85 + 5;
            if deltaY < app.UIFigure.Position(4) - 100
                newBottom   = yTop - deltaY;
                app.SegTable.Position(2) = newBottom;
                app.SegTable.Position(4) = deltaY;
            end
        end
    end
    
    methods (Access = public)
        
        function Show(app)
            app.UIFigure.Visible = 'off';
            app.ResetHighlights()
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
            drawnow

            app.GenerateDatabase()
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, databasePath)
            %todo, create database.json file, read it

            app.mainApp = mainApp;
            app.dbPath  = databasePath;
            app.UIFigure.Visible = 'off';

            app.PatientTable.FontUnits = 'Inches';
            app.PatientTable.FontSize = 1/8;
            app.SegTable.FontUnits = 'Inches';
            app.SegTable.FontSize = 1/8;
        end

        % Callback function: PatientTable
        function PatientTableSelectionChanged(app, event)
            selection   = app.PatientTable.Selection;
            
            pid         = app.pTable{selection, 1};
            session     = app.pTable{selection, 2};

            sessID      = fullfile(app.dbPath, pid, session, 'rmsstudio');

            highlightIdx= find(ismember(app.sessionLst, sessID));
            app.HighlightRows(highlightIdx)

        end

        % Callback function: SegTable
        function SegTableSelectionChanged(app, event)
            selection = app.SegTable.Selection;
        end

        % Close request function: UIFigure
        function Cancel(app, event)
            app.UIFigure.Visible = 'off';
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SegManager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @Cancel, true);
            app.PatientTable = uitable(app.UIFigure);
            app.PatientTable.ColumnName = {'Patient_ID'; 'Session'; 'Segmentation'};
            app.PatientTable.RowName = {};
            app.PatientTable.SelectionType = 'row';
            app.PatientTable.ColumnEditable = [false false false];
            app.PatientTable.SelectionChangedFcn = createCallbackFcn(app, @PatientTableSelectionChanged, true);
            app.SegTable = uitable(app.UIFigure);
            app.SegTable.ColumnName = {'Profile'; 'Scan'; 'Name'; 'Slice'; 'Comment'};
            app.SegTable.RowName = {};
            app.SegTable.SelectionType = 'row';
            app.SegTable.ColumnEditable = [false false false false true];
            app.SegTable.SelectionChangedFcn = createCallbackFcn(app, @SegTableSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end