classdef TURQUOISE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadDatasetMenu              matlab.ui.container.Menu
        LoadStudyMenu                matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        StudyMenu                    matlab.ui.container.Menu
        SwitchUserMenu               matlab.ui.container.Menu
        ReloadStudyMenu              matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        CoronalMenu                  matlab.ui.container.Menu
        SagittalMenu                 matlab.ui.container.Menu
        AxialMenu                    matlab.ui.container.Menu
        SwitchViewMenu               matlab.ui.container.Menu
        View1Menu                    matlab.ui.container.Menu
        View2Menu                    matlab.ui.container.Menu
        Labels3DMenu                 matlab.ui.container.Menu
        AnalyseMenu                  matlab.ui.container.Menu
        PatientReportMenu            matlab.ui.container.Menu
        AIHelperMenu                 matlab.ui.container.Menu
        DrawMeasureMenu              matlab.ui.container.Menu
        DrawMenu                     matlab.ui.container.Menu
        DrawPolygonMenu              matlab.ui.container.Menu
        DrawCircleMenu               matlab.ui.container.Menu
        DrawEllipseMenu              matlab.ui.container.Menu
        MagicDrawMenu                matlab.ui.container.Menu
        MeasurementsMenu             matlab.ui.container.Menu
        LineMenu                     matlab.ui.container.Menu
        EllipseMenu                  matlab.ui.container.Menu
        ClearMenu                    matlab.ui.container.Menu
        UndoMenu                     matlab.ui.container.Menu
        RemoveSingleMenu             matlab.ui.container.Menu
        RemoveAllMenu                matlab.ui.container.Menu
        SelectionSensitivityMenu     matlab.ui.container.Menu
        CopyMenu                     matlab.ui.container.Menu
        LoadsegmentationMenu         matlab.ui.container.Menu
        LoadfromfileMenu             matlab.ui.container.Menu
        SelectfromstudyMenu          matlab.ui.container.Menu
        Settings                     matlab.ui.container.Menu
        DatasetMenu                  matlab.ui.container.Menu
        OverviewMenu                 matlab.ui.container.Menu
        NewdatasetMenu               matlab.ui.container.Menu
        ComputeMenu                  matlab.ui.container.Menu
        ADCIVIMMenu                  matlab.ui.container.Menu
        PluginsMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        SwitchStudyButton            matlab.ui.control.Button
        HoverLabel                   matlab.ui.control.Label
        ProfilesLabel                matlab.ui.control.Label
        ProfileListBox               matlab.ui.control.ListBox
        SessionsListBox              matlab.ui.control.ListBox
        SessionsLabel                matlab.ui.control.Label
        UOBox                        matlab.ui.control.ListBox
        UserObjectsLabel             matlab.ui.control.Label
        AvailableimagesListBox       matlab.ui.control.ListBox
        AvailableimagesListBoxLabel  matlab.ui.control.Label
        DrawPolygonButton            matlab.ui.control.Button
        PatientsListBox              matlab.ui.control.ListBox
        PatientsListBoxLabel         matlab.ui.control.Label
        DSlider                      matlab.ui.control.Slider
        DSliderLabel                 matlab.ui.control.Label
        Increase4DButton             matlab.ui.control.Button
        Decrease4DButton             matlab.ui.control.Button
        SliceIncreaseButton          matlab.ui.control.Button
        SliceDecreaseButton          matlab.ui.control.Button
        MaxvalSlider                 matlab.ui.control.Slider
        MaxvalSliderLabel            matlab.ui.control.Label
        MinvalSlider                 matlab.ui.control.Slider
        MinvalSliderLabel            matlab.ui.control.Label
        SliceSlider                  matlab.ui.control.Slider
        SliceSliderLabel             matlab.ui.control.Label
        AxialButton                  matlab.ui.control.Button
        SagittalButton               matlab.ui.control.Button
        CoronalButton                matlab.ui.control.Button
        UIAxes1                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        EditpointsMenu               matlab.ui.container.Menu
        DeleteMenu                   matlab.ui.container.Menu
        RenameMenu                   matlab.ui.container.Menu
        CopytoMenu                   matlab.ui.container.Menu
        ToggleadditiveMenu           matlab.ui.container.Menu
        ViewMenu_2                   matlab.ui.container.Menu
        PickColorMenu                matlab.ui.container.Menu
        ToggleMaskOutlineMenu        matlab.ui.container.Menu
        ToggleVisibilityMenu         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %GUI
        drawing,                    % Information for drawing
        buttonDown  = false         % Stores whether or not mouse is being dragged
        axID,                       % UIAxis which is currently in focus
        imID,                       % Index of the image in focus
        MinValue,                   % Minimum image value (everything <= MinValue -> 0)
        MaxValue,                   % Maximum image value (everything >= MaxValue -> 255)
        cScalePerImage              % Min and max display value per image
        imagePerAxis                %Stores which image is used per axis
        viewingTransMat             %Stores the transformation matrix used to go from image space to world coordinates, at the current viewing position
        viewingParams               %Stores translation & scaling mutations to be made to viewingTransMat
                                    % = [deltaX, deltaY, deltaZ, zoomFactor, i,j,k]  ( ijk to center)
        slicePerImage               %Stores which slice is used per image
        crosshairPerAxis            % I & J locations of the crosshair per axis
        interactionTimer            %Timer that keeps track of how long ago the user was scrolling through an image
        cancelTimer                 %Timer that periodically checks whether the user has canceled a progressdialogue
        d4PerImage                  %Stores the 4d axis per image
        viewPerImage                %Stores which view (ax, cor, sag) is used per image, TODO: replace with viewPerAxis
        viewPerAxis                 %Stores which view (ax, cor, sag) is used per axis
        zoomPerImage                %Stores the Xlim and YLim per image
        transMatPerImage            %Stores the transformation matrix per image
        busyStatus                  % Whether the app is currently busy
        unsavedProgress             % Stores whether any unsaved progress exists
        progressDlg                 %Progressdialogue
        tmpMaskOpacity              = 0.4 
        tmpMaskColor                = [0, 1, 1];
        pluginSubMenus              = {} %Stores the submenus to be added to the pluginMenu
        
        %Study
        backup_list                 % Stores a backup of all previous states
        backup_idx                  % idx of which backup is the most recent
        userObjects,                % Stores all the user-drawn objects
        data,                       % The images
        UOMask                      %Stores the ID of which UO is drawn on this pixel
        tmpMasks
        
        
        %temporary items
        points                      % Stores all current drawing points
        dragPoint                   %Stores the location from where to 
                                    % start dragging roi points.
        currentCircle               %Circular ROI which is currently being 
                                    % drawn
        tempDrawings                % All pointers to temporary graphics items (help lines, etc) are stored here.

        %Image
        imageRenderer               %Object storing the rendered images.
        textRenderer                %Object for storing text shown on screen
        UORenderer                  %Object for storing the rendered user objects (ROIs etc)
        tmpRenderer
        crosshairRenderer           %Object for storing the crosshair
        
        %Other
        user_profile                %Stores which user is currently working
        dataset                     %Cell array of all study directories in dataset
        datasetPath                 %Path to folder with the study directories
        studyPath                   %Path to folder with the current study (= patient)
        sessionPath                 %Path to folder with the current session 
        sessionNames                 %Filenames of all the images in the current session.
        hasUO                       %Stores which images have user-objects 
        plugins                     = {} %Stores all the plugins

        % filepath                    %Stores the path to the current patient / study
        % current_folder              %Stores the path to the current rmsstudio dir
        %TODO: replace both with app.dataset


        Align                       %Stores align status (lr, rl)
        interpolateImages           = false %Boolean
        ctrl                        %Keeps track of the ctrl modifier
        lastEvents                  %Keeps track of when the last event was
        
        %Sub-apps
        ROIPromptApp                %Used to prompt user object names
        SegManagerApp               %Used to list and manage all segmentations
        PatientReportApp            %Used to run patient reports
        AIHelperApp                 %Used to train/infer AI models
        ContourPickerApp            %Used to select contour ROIs
        CoordinateInspectorApp      % tmp app to test things

    end
    
    methods (Access = public)
    

        % Just an wrapper for convenience
        function MouseClickedInImage(app,im,hit)
            Interaction.MouseClickedInImage(app, hit); 
        end
        
        function MouseDraggedInImage(app, im, hit)
            Interaction.MouseDraggedInImage(app, hit);
        end
        
        function MouseReleasedInImage(app, im, hit)
            Interaction.MouseReleasedInImage(app, hit)
        end
        
        function MouseMoveInImage(app, im, hit)
            Interaction.MouseMoveInImage(app, hit)
        end
    
        % Update the Min-Max slider labels
        function UpdateMinMaxSlider(app)
            GUI.UpdateMinMaxSlider(app)
        end
    
        function ax = GetAxis(app, idx)
            %Handle for linking to the intended axis. Useful since the
            %axes can't be stored in a list it seems.
            
            if idx == 1
                ax = app.UIAxes1;
            elseif idx == 2
                ax = app.UIAxes2;
            else
                error("That figure doesn't exist")
            end
        end

        %Called from the Launcher app. Returns the new profiles and datsets
        %to be stored with guidata. Also returns the selected profile and
        %dataset.
        function LaunchFromLauncher(app, datasets, profiles, dataset, profile)

            setpref('rmsstudio', 'datasets', datasets);
            setpref('rmsstudio', 'profiles', profiles);

            Database.PrepareDatabase(app, dataset, profile)

            %Init other apps to speed up showing them later
            app.ROIPromptApp = ROIPrompt(app);
            app.SegManagerApp = SegManager(app, app.datasetPath);
            app.PatientReportApp = PatientReport(app);
            app.ContourPickerApp = ContourPicker(app);
            
        end


        function ROIPromptSlot(app, ROILst, ROIName, newROI)
            %Triggers when the ROIPrompt GUI exits. 
            %Stores the total ROI list as an app preference
            %Creates / renames ROIs

            %inputs:    ROILst, list of all available ROIs
            %           ROIName, selected name
            %           newROI, true when new ROI was made

            setpref('rmsstudio', 'ROILst', ROILst);
            GUI.RevertControlsStatus(app);

            %Check if any UOs are named _tmp_, if so, rename them
            for i = 1:length(app.userObjects)
                obj = app.userObjects{i};
                if obj.renaming && ~obj.deleted
                    Objects.ChangeName(app, obj.ID, ROIName)
                    return
                end
            end

            if isempty(ROIName)
                return
            end

            %If no UO's are renamed, create a new UO with the current
            %drawing points
            ROI.ValidateDrawingPoints(app, ROIName, newROI)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %add code dependencies
            [folder, ~, ~]      = fileparts(which('TURQUOISE'));
            addpath([folder '\nifti']);
            addpath([ folder '\otherLibs'])
            addpath([folder '\subApps'])
            addpath([folder '\plugins'])

            %Setup render enginge
            set(0, 'DefaultFigureRenderer', 'opengl');
            opengl('hardware');

            %Create memory to store rendered images & text
            %initial image
            app.imageRenderer       = {[],[]};
            app.textRenderer        = {[],[]};
            app.UORenderer          = {{},{}};
            app.tmpRenderer         = {{},{}};
            app.crosshairRenderer   = {[],[]};

            %Initialize UIAxes objects
            GUI.SetupAxis(app, app.UIAxes1, 1);
            GUI.SetupAxis(app, app.UIAxes2, 2);
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
            
            %setup callbacks
            set(app.imageRenderer{1},...
                'ButtonDownFcn',@app.MouseClickedInImage);
            set(app.imageRenderer{2},...
                'ButtonDownFcn',@app.MouseClickedInImage);
            set(app.UIFigure, 'WindowButtonMotionFcn',@app.MouseDraggedInImage)
            

            %%%%%%%%  Timers
            %The interaction timer is used to check whether the using has
            %recently been scrolling through any images
            app.interactionTimer = timer('TimerFcn', ...
                {@Interaction.InteractionTimerCallback, app}, ...
                'StartDelay', 0.3);

            %Setup canceltimer - periodically checks if the user has
            %cancelled the uiprogressdialogue
            app.cancelTimer = timer('TimerFcn', ...
                {@Interaction.CancelTimerCallback, app}, ...
                'StartDelay', 2);
            start(app.cancelTimer)


            %Initialize some variables that track the GUI state
            app.axID                = 1;            
            %drawing mode stores what mouse-interactions should do
            %TODO: this should be stored as a string, not a number
            % 0 = nothing
            % 1 = ROI
            % 2 = edit ROI
            % 3 = line-measure
            % 4 = 'magic'
            % 5 = Circular ROI
            app.drawing.mode                = 0;
            app.tempDrawings                = [];
                        

            %init some variables
            app.imID            = 1;
            app.axID            = 1;
            app.imagePerAxis    = [1,1];
            app.slicePerImage   = [];
            app.unsavedProgress = false;
            

            %Setup user preferences
            IOUtils.InitPreferences()

            %Setup Plugins
            PluginUtils.populatePlugins(app)

            %Launch through launcher
            Launcher(app, ...
                    getpref('rmsstudio', 'datasets'), ...
                    getpref('rmsstudio', 'profiles'));            
        end

        % Menu selected function: LoadStudyMenu
        function LoadButtonPushed(app, event)
            Interaction.LoadStudy(app)
        end

        % Callback function: AxialButton, AxialMenu
        function AxialButtonPushed(app, event)
            Interaction.ChangeViewAxis(app, 3)
        end

        % Callback function: SagittalButton, SagittalMenu
        function SagittalButtonPushed(app, event)
            Interaction.ChangeViewAxis(app, 2)
        end

        % Callback function: CoronalButton, CoronalMenu
        function CoronalButtonPushed(app, event)
            Interaction.ChangeViewAxis(app, 1)
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            view    = app.viewPerImage(app.imID);
            slice   = app.slicePerImage{app.imID}{view};

            GUI.Scroll(app, slice - changingValue, app.axID)
        end

        % Menu selected function: RemoveSingleMenu
        function SelectDeleteButtonPushed(app, event)
            Interaction.RemoveSingle(app)
        end

        % Menu selected function: SaveMenu
        function SaveeditedsegmButtonPushed(app, event)
            Interaction.Save(app);
        end

        % Value changed function: MaxvalSlider
        function MaxvalSliderValueChanging(app, event)
            GUI.UpdateMaxContrast(app, event.Value)
        end

        % Value changed function: MinvalSlider
        function MinvalSliderValueChanging(app, event)
           GUI.UpdateMinContrast(app, event.Value)
        end

        % Menu selected function: UndoMenu
        function UndoButtonPushed(app, event)
            Interaction.Undo(app)
        end

        % Menu selected function: SelectionSensitivityMenu
        function SelectionsensitivityButtonPushed(app, event)
            answer = inputdlg(['Please input the new sensitivity in pixel, (current ' num2str(app.selector_size) ')']);
            if(isempty(answer))
                return
            end
            answer = answer{1};
            if(ischar(answer))
                answer = str2double(answer);
            end
            if(answer > 0 && answer <= 10)
                app.selector_size = answer;
            else
                uialert(uifigure,'Invalid values! 0 < x <= 10 pixels','');
            end
        end

        % Callback function: DrawPolygonButton, DrawPolygonMenu
        function DrawpolygonButtonPushed(app, event)
            Interaction.DrawPolygon(app)
        end

        % Menu selected function: RemoveAllMenu
        function DeleteROIsALLButtonPushed(app, event)
            Interaction.DeleteROIsAndMeasurements(app)
        end

        % Menu selected function: MagicDrawMenu
        function MagicdrawButtonPushed(app, event)
            Interaction.MagicDraw(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            Interaction.UIKeyPress(app, event)
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            GUI.Scroll(app, event)
        end

        % Value changed function: AvailableimagesListBox
        function AvailableimagesListBoxValueChanged(app, event)
            Interaction.ListBoxChanged(app)
        end

        % Menu selected function: ReloadStudyMenu
        function ResetstudyButtonPushed(app, event)
            Interaction.ResetStudy(app)
        end

        % Menu selected function: LineMenu
        function MeasureLineButtonPushed(app, event)
            Interaction.MeasureLine(app)
        end

        % Button pushed function: SliceDecreaseButton
        function SliceDecreaseButtonPushed(app, event)
            GUI.Scroll(app, 1, app.axID)
        end

        % Button pushed function: SliceIncreaseButton
        function SliceIncreaseButtonPushed(app, event)
            GUI.Scroll(app, -1, app.axID)  
        end

        % Menu selected function: EllipseMenu
        function MeasureAutoButtonPushed(app, event)
           Interaction.PerformAutomaticEllipseMeasurement(app);
        end

        % Button pushed function: Decrease4DButton
        function Decrease4DButtonPushed(app, event)
            Interaction.Update4D(app, ...
                app.d4PerImage(app.imID) - 1)
        end

        % Button pushed function: Increase4DButton
        function Increase4DButtonPushed(app, event)
            Interaction.Update4D(app, ...
                app.d4PerImage(app.imID) + 1)       
        end

        % Value changing function: DSlider
        function DSliderValueChanging(app, event)
            changingValue = event.Value;
            Interaction.Update4D(app, round(changingValue))                      
        end

        % Menu selected function: View1Menu
        function View1ButtonPushed(app, event)
            Backups.CreateBackup(app)
            Interaction.SwitchViewAndFocus(app,1,'View1Button');
        end

        % Menu selected function: View2Menu
        function View2ButtonPushed(app, event)
            Backups.CreateBackup(app)
            Interaction.SwitchViewAndFocus(app,2,'View2Button');
        end

        % Menu selected function: Labels3DMenu
        function Labels3DButtonPushed(app, event)
            Plotting.Labels3DView(app);
        end

        % Menu selected function: SwitchUserMenu
        function SwitchUserButtonPressed(app, event)
            Interaction.PromptProfile(app)     
        end

        % Menu selected function: LoadDatasetMenu
        function LoadSetButtonPushed(app, event)
            Database.PrepareDatabase(app)
        end

        % Value changed function: UOBox
        function UOBoxValueChanged(app, event)
            Interaction.UOBoxChanged(app)
        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            Database.SwitchPatient(app)
        end

        % Menu selected function: LoadfromfileMenu
        function LoadLabelsButtonPushed(app, event)
            %Loads a new segmentation for the current image.
            Interaction.LoadNewLabels(app)
        end

        % Callback function
        function loadROIPointsButtonPushed(app, event)
          Interaction.LoadROIPoints(app)  
        end

        % Menu selected function: DrawCircleMenu
        function CircularROI(app, event)
            Interaction.CircularROI(app)
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            Interaction.UIKeyRelease(app, event)
        end

        % Menu selected function: DeleteMenu
        function DeleteUO(app, event)
            Objects.DeleteUO(app);
        end

        % Menu selected function: CopytoMenu
        function CopyUO(app, event)
            Objects.CopyUOTo(app)
        end

        % Menu selected function: RenameMenu
        function RenameUO(app, event)
            Objects.RenameUO(app)
        end

        % Menu selected function: ToggleVisibilityMenu
        function ToggleUO(app, event)
            Objects.ToggleVisibleUO(app)
        end

        % Menu selected function: ExitMenu
        function ExitProgram(app, event)
            Interaction.Exit(app)
        end

        % Menu selected function: EditpointsMenu
        function EditUO(app, event)
            Objects.EditUO(app)
        end

        % Menu selected function: DrawEllipseMenu
        function EllipseROI(app, event)
            Interaction.EllipseROI(app)
        end

        % Menu selected function: OverviewMenu
        function OverviewMenuPressed(app, event)
            app.SegManagerApp.Show()
        end

        % Menu selected function: NewdatasetMenu
        function OpenPreprocessingView(app, event)
            
        end

        % Menu selected function: CopyMenu
        function CopyPressed(app, event)
            CopyRegisterSeg.CopySegmentations(app)
        end

        % Menu selected function: ToggleadditiveMenu
        function ToggleUOAdditive(app, event)
            Objects.ChangeUOAdditiveSubtractive(app)
        end

        % Value changed function: ProfileListBox
        function SwitchProfile(app, event)
            value = app.ProfileListBox.Value;
            if strcmp(value, 'None')
                value = '';
            end
            app.user_profile = value;

            Interaction.SwitchUserProfile(app)
        end

        % Value changed function: SessionsListBox
        function SessionsListBoxValueChanged(app, event)
            Database.SwitchSession(app)
        end

        % Button pushed function: SwitchStudyButton
        function SwitchToNewStudy(app, event)
            Database.SwitchToNewSession(app)
        end

        % Menu selected function: ADCIVIMMenu
        function ComputeADCIVIM(app, event)
            selection = Interaction.PromptTarget(app, true);

            if isempty(selection)
                return
            end

            GUI.DisableControlsStatus(app, "Computing ADC/IVIM")

            if length(selection) > 1
                for i = 1:length(selection)
                    target = selection(i);
                    
                    fn = fullfile(app.sessionPath, [app.sessionNames{target}, '.nii.gz']);

                    GUI.UpdateProgressDialogue(app, ...
                        fn, ...
                        i / length(selection))

                    NewDatasetUtils.DWIConv(fn)                    
                end
            else
                fn = fullfile(app.sessionPath, [app.sessionNames{selection}, '.nii.gz']);

                GUI.UpdateProgressDialogue(app, fn)

                NewDatasetUtils.DWIConv(fn)
            end

            GUI.RevertControlsStatus(app)
            
            
        end

        % Menu selected function: PatientReportMenu
        function RunPatientReport(app, event)
            fp = fullfile(app.studyPath);
            
            app.PatientReportApp.Run(fp, 'rmsstudio');
        end

        % Menu selected function: SelectfromstudyMenu
        function LoadLabelsFromStudy(app, event)
            Interaction.LoadLabelsFromStudy(app)
        end

        % Menu selected function: AIHelperMenu
        function LaunchAIHelper(app, event)
            AIHelper(app.datasetPath);
        end

        % Menu selected function: PickColorMenu
        function PickUOColor(app, event)
            Objects.PickColor(app);
        end

        % Menu selected function: ToggleMaskOutlineMenu
        function ToggleUOMaskOutline(app, event)
            Objects.ToggleMaskOutline(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'TURQUOISE';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDatasetMenu = uimenu(app.FileMenu);
            app.LoadDatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSetButtonPushed, true);
            app.LoadDatasetMenu.Text = 'Load Dataset';
            app.LoadStudyMenu = uimenu(app.FileMenu);
            app.LoadStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadStudyMenu.Text = 'Load Study';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveeditedsegmButtonPushed, true);
            app.SaveMenu.Text = 'Save';
            app.StudyMenu = uimenu(app.FileMenu);
            app.StudyMenu.Text = 'Study';
            app.SwitchUserMenu = uimenu(app.StudyMenu);
            app.SwitchUserMenu.MenuSelectedFcn = createCallbackFcn(app, @SwitchUserButtonPressed, true);
            app.SwitchUserMenu.Text = 'Switch User';
            app.ReloadStudyMenu = uimenu(app.StudyMenu);
            app.ReloadStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetstudyButtonPushed, true);
            app.ReloadStudyMenu.Text = 'Reload Study';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitProgram, true);
            app.ExitMenu.Text = 'Exit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Enable = 'off';
            app.ViewMenu.Text = 'View';
            app.CoronalMenu = uimenu(app.ViewMenu);
            app.CoronalMenu.MenuSelectedFcn = createCallbackFcn(app, @CoronalButtonPushed, true);
            app.CoronalMenu.Text = 'Coronal';
            app.SagittalMenu = uimenu(app.ViewMenu);
            app.SagittalMenu.MenuSelectedFcn = createCallbackFcn(app, @SagittalButtonPushed, true);
            app.SagittalMenu.Text = 'Sagittal';
            app.AxialMenu = uimenu(app.ViewMenu);
            app.AxialMenu.MenuSelectedFcn = createCallbackFcn(app, @AxialButtonPushed, true);
            app.AxialMenu.Text = 'Axial';
            app.SwitchViewMenu = uimenu(app.ViewMenu);
            app.SwitchViewMenu.Text = 'Switch View';
            app.View1Menu = uimenu(app.SwitchViewMenu);
            app.View1Menu.MenuSelectedFcn = createCallbackFcn(app, @View1ButtonPushed, true);
            app.View1Menu.Text = 'View 1';
            app.View2Menu = uimenu(app.SwitchViewMenu);
            app.View2Menu.MenuSelectedFcn = createCallbackFcn(app, @View2ButtonPushed, true);
            app.View2Menu.Text = 'View 2';
            app.Labels3DMenu = uimenu(app.ViewMenu);
            app.Labels3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Labels3DButtonPushed, true);
            app.Labels3DMenu.Text = 'Labels 3D';
            app.AnalyseMenu = uimenu(app.UIFigure);
            app.AnalyseMenu.Enable = 'off';
            app.AnalyseMenu.Text = 'Analyse';
            app.PatientReportMenu = uimenu(app.AnalyseMenu);
            app.PatientReportMenu.MenuSelectedFcn = createCallbackFcn(app, @RunPatientReport, true);
            app.PatientReportMenu.Text = 'Patient Report';
            app.AIHelperMenu = uimenu(app.AnalyseMenu);
            app.AIHelperMenu.MenuSelectedFcn = createCallbackFcn(app, @LaunchAIHelper, true);
            app.AIHelperMenu.Text = 'AI Helper';
            app.DrawMeasureMenu = uimenu(app.UIFigure);
            app.DrawMeasureMenu.Enable = 'off';
            app.DrawMeasureMenu.Text = 'Draw / Measure';
            app.DrawMenu = uimenu(app.DrawMeasureMenu);
            app.DrawMenu.Text = 'Draw';
            app.DrawPolygonMenu = uimenu(app.DrawMenu);
            app.DrawPolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawpolygonButtonPushed, true);
            app.DrawPolygonMenu.Text = 'Draw Polygon';
            app.DrawCircleMenu = uimenu(app.DrawMenu);
            app.DrawCircleMenu.MenuSelectedFcn = createCallbackFcn(app, @CircularROI, true);
            app.DrawCircleMenu.Text = 'Draw Circle';
            app.DrawEllipseMenu = uimenu(app.DrawMenu);
            app.DrawEllipseMenu.MenuSelectedFcn = createCallbackFcn(app, @EllipseROI, true);
            app.DrawEllipseMenu.Text = 'Draw Ellipse';
            app.MagicDrawMenu = uimenu(app.DrawMenu);
            app.MagicDrawMenu.MenuSelectedFcn = createCallbackFcn(app, @MagicdrawButtonPushed, true);
            app.MagicDrawMenu.Text = 'Magic Draw';
            app.MeasurementsMenu = uimenu(app.DrawMeasureMenu);
            app.MeasurementsMenu.Text = 'Measurements';
            app.LineMenu = uimenu(app.MeasurementsMenu);
            app.LineMenu.MenuSelectedFcn = createCallbackFcn(app, @MeasureLineButtonPushed, true);
            app.LineMenu.Text = 'Line';
            app.EllipseMenu = uimenu(app.MeasurementsMenu);
            app.EllipseMenu.MenuSelectedFcn = createCallbackFcn(app, @MeasureAutoButtonPushed, true);
            app.EllipseMenu.Text = 'Ellipse';
            app.ClearMenu = uimenu(app.DrawMeasureMenu);
            app.ClearMenu.Text = 'Clear';
            app.UndoMenu = uimenu(app.ClearMenu);
            app.UndoMenu.MenuSelectedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoMenu.Text = 'Undo';
            app.RemoveSingleMenu = uimenu(app.ClearMenu);
            app.RemoveSingleMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectDeleteButtonPushed, true);
            app.RemoveSingleMenu.Text = 'Remove Single';
            app.RemoveAllMenu = uimenu(app.ClearMenu);
            app.RemoveAllMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteROIsALLButtonPushed, true);
            app.RemoveAllMenu.Text = 'Remove All';
            app.SelectionSensitivityMenu = uimenu(app.ClearMenu);
            app.SelectionSensitivityMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectionsensitivityButtonPushed, true);
            app.SelectionSensitivityMenu.Text = 'Selection Sensitivity';
            app.CopyMenu = uimenu(app.DrawMeasureMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyPressed, true);
            app.CopyMenu.Text = 'Copy';
            app.LoadsegmentationMenu = uimenu(app.DrawMeasureMenu);
            app.LoadsegmentationMenu.Text = 'Load segmentation';
            app.LoadfromfileMenu = uimenu(app.LoadsegmentationMenu);
            app.LoadfromfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadLabelsButtonPushed, true);
            app.LoadfromfileMenu.Text = 'Load from file';
            app.SelectfromstudyMenu = uimenu(app.LoadsegmentationMenu);
            app.SelectfromstudyMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadLabelsFromStudy, true);
            app.SelectfromstudyMenu.Text = 'Select from study';
            app.Settings = uimenu(app.UIFigure);
            app.Settings.Text = 'Settings';
            app.DatasetMenu = uimenu(app.UIFigure);
            app.DatasetMenu.Text = 'Dataset';
            app.OverviewMenu = uimenu(app.DatasetMenu);
            app.OverviewMenu.MenuSelectedFcn = createCallbackFcn(app, @OverviewMenuPressed, true);
            app.OverviewMenu.Text = 'Overview';
            app.NewdatasetMenu = uimenu(app.DatasetMenu);
            app.NewdatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenPreprocessingView, true);
            app.NewdatasetMenu.Text = 'New dataset';
            app.ComputeMenu = uimenu(app.DatasetMenu);
            app.ComputeMenu.Text = 'Compute';
            app.ADCIVIMMenu = uimenu(app.ComputeMenu);
            app.ADCIVIMMenu.MenuSelectedFcn = createCallbackFcn(app, @ComputeADCIVIM, true);
            app.ADCIVIMMenu.Text = 'ADC / IVIM';
            app.PluginsMenu = uimenu(app.UIFigure);
            app.PluginsMenu.Text = 'Plugins';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            app.UIAxes1 = uiaxes(app.GridLayout);
            app.CoronalButton = uibutton(app.GridLayout, 'push');
            app.CoronalButton.ButtonPushedFcn = createCallbackFcn(app, @CoronalButtonPushed, true);
            app.CoronalButton.Text = 'Coronal';
            app.SagittalButton = uibutton(app.GridLayout, 'push');
            app.SagittalButton.ButtonPushedFcn = createCallbackFcn(app, @SagittalButtonPushed, true);
            app.SagittalButton.Text = 'Sagittal';
            app.AxialButton = uibutton(app.GridLayout, 'push');
            app.AxialButton.ButtonPushedFcn = createCallbackFcn(app, @AxialButtonPushed, true);
            app.AxialButton.Text = 'Axial';
            app.SliceSliderLabel = uilabel(app.GridLayout);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.GridLayout);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.MinvalSliderLabel = uilabel(app.GridLayout);
            app.MinvalSliderLabel.Text = 'Min val';
            app.MinvalSlider = uislider(app.GridLayout);
            app.MinvalSlider.ValueChangedFcn = createCallbackFcn(app, @MinvalSliderValueChanging, true);
            app.MaxvalSliderLabel = uilabel(app.GridLayout);
            app.MaxvalSliderLabel.Text = 'Max val';
            app.MaxvalSlider = uislider(app.GridLayout);
            app.MaxvalSlider.ValueChangedFcn = createCallbackFcn(app, @MaxvalSliderValueChanging, true);
            app.SliceDecreaseButton = uibutton(app.GridLayout, 'push');
            app.SliceDecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @SliceDecreaseButtonPushed, true);
            app.SliceDecreaseButton.Text = '-';
            app.SliceIncreaseButton = uibutton(app.GridLayout, 'push');
            app.SliceIncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @SliceIncreaseButtonPushed, true);
            app.SliceIncreaseButton.Text = '+';
            app.Decrease4DButton = uibutton(app.GridLayout, 'push');
            app.Decrease4DButton.ButtonPushedFcn = createCallbackFcn(app, @Decrease4DButtonPushed, true);
            app.Decrease4DButton.Text = '-';
            app.Increase4DButton = uibutton(app.GridLayout, 'push');
            app.Increase4DButton.ButtonPushedFcn = createCallbackFcn(app, @Increase4DButtonPushed, true);
            app.Increase4DButton.Text = '+';
            app.DSliderLabel = uilabel(app.GridLayout);
            app.DSliderLabel.Text = '4D';
            app.DSlider = uislider(app.GridLayout);
            app.DSlider.ValueChangingFcn = createCallbackFcn(app, @DSliderValueChanging, true);
            app.PatientsListBoxLabel = uilabel(app.GridLayout);
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.GridLayout);
            app.PatientsListBox.Items = {};
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Value = {};
            app.DrawPolygonButton = uibutton(app.GridLayout, 'push');
            app.DrawPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @DrawpolygonButtonPushed, true);
            app.DrawPolygonButton.Text = 'Draw Polygon';
            app.AvailableimagesListBoxLabel = uilabel(app.GridLayout);
            app.AvailableimagesListBoxLabel.Text = 'Available images';
            app.AvailableimagesListBox = uilistbox(app.GridLayout);
            app.AvailableimagesListBox.Items = {};
            app.AvailableimagesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableimagesListBoxValueChanged, true);
            app.AvailableimagesListBox.Value = {};
            app.UserObjectsLabel = uilabel(app.GridLayout);
            app.UserObjectsLabel.Text = 'User Objects';
            app.UOBox = uilistbox(app.GridLayout);
            app.UOBox.Items = {};
            app.UOBox.ValueChangedFcn = createCallbackFcn(app, @UOBoxValueChanged, true);
            app.UOBox.Value = {};
            app.SessionsLabel = uilabel(app.GridLayout);
            app.SessionsLabel.Text = 'Sessions';
            app.SessionsListBox = uilistbox(app.GridLayout);
            app.SessionsListBox.Items = {};
            app.SessionsListBox.ValueChangedFcn = createCallbackFcn(app, @SessionsListBoxValueChanged, true);
            app.SessionsListBox.Value = {};
            app.ProfileListBox = uilistbox(app.GridLayout);
            app.ProfileListBox.Items = {};
            app.ProfileListBox.ValueChangedFcn = createCallbackFcn(app, @SwitchProfile, true);
            app.ProfileListBox.Value = {};
            app.ProfilesLabel = uilabel(app.GridLayout);
            app.ProfilesLabel.Text = 'Profiles';
            app.HoverLabel = uilabel(app.GridLayout);
            app.SwitchStudyButton = uibutton(app.GridLayout, 'push');
            app.SwitchStudyButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchToNewStudy, true);
            app.SwitchStudyButton.Text = 'Load';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.EditpointsMenu = uimenu(app.ContextMenu);
            app.EditpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @EditUO, true);
            app.EditpointsMenu.Text = 'Edit points';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteUO, true);
            app.DeleteMenu.Text = 'Delete';
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameUO, true);
            app.RenameMenu.Text = 'Rename';
            app.CopytoMenu = uimenu(app.ContextMenu);
            app.CopytoMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyUO, true);
            app.CopytoMenu.Text = 'Copy to';
            app.ToggleadditiveMenu = uimenu(app.ContextMenu);
            app.ToggleadditiveMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleUOAdditive, true);
            app.ToggleadditiveMenu.Text = 'Toggle additive';
            app.ViewMenu_2 = uimenu(app.ContextMenu);
            app.ViewMenu_2.Text = 'View';
            app.PickColorMenu = uimenu(app.ViewMenu_2);
            app.PickColorMenu.MenuSelectedFcn = createCallbackFcn(app, @PickUOColor, true);
            app.PickColorMenu.Text = 'Pick Color';
            app.ToggleMaskOutlineMenu = uimenu(app.ViewMenu_2);
            app.ToggleMaskOutlineMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleUOMaskOutline, true);
            app.ToggleMaskOutlineMenu.Text = 'Toggle Mask/Outline';
            app.ToggleVisibilityMenu = uimenu(app.ViewMenu_2);
            app.ToggleVisibilityMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleUO, true);
            app.ToggleVisibilityMenu.Text = 'Toggle Visibility';
            % Assign app.ContextMenu
            app.UOBox.ContextMenu = app.ContextMenu;
            app.ProfileListBox.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TURQUOISE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end